classdef ReverbGUI_s1505528_Tingey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RoomDimensionsPanel             matlab.ui.container.Panel
        LxEditFieldLabel                matlab.ui.control.Label
        LxEditField                     matlab.ui.control.NumericEditField
        LyEditFieldLabel                matlab.ui.control.Label
        LyEditField                     matlab.ui.control.NumericEditField
        LzEditFieldLabel                matlab.ui.control.Label
        LzEditField                     matlab.ui.control.NumericEditField
        AbsorptioncoefficientsPanel     matlab.ui.container.Panel
        alpha2EditFieldLabel            matlab.ui.control.Label
        alpha2EditField                 matlab.ui.control.NumericEditField
        alpha4EditFieldLabel            matlab.ui.control.Label
        alpha4EditField                 matlab.ui.control.NumericEditField
        alpha1EditFieldLabel            matlab.ui.control.Label
        alpha1EditField                 matlab.ui.control.NumericEditField
        alpha3EditFieldLabel            matlab.ui.control.Label
        alpha3EditField                 matlab.ui.control.NumericEditField
        alpha5EditFieldLabel            matlab.ui.control.Label
        alpha5EditField                 matlab.ui.control.NumericEditField
        alpha6EditFieldLabel            matlab.ui.control.Label
        alpha6EditField                 matlab.ui.control.NumericEditField
        PositionofSourcePanel           matlab.ui.container.Panel
        rEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        qEditFieldLabel                 matlab.ui.control.Label
        qEditField                      matlab.ui.control.NumericEditField
        pEditFieldLabel                 matlab.ui.control.Label
        pEditField                      matlab.ui.control.NumericEditField
        PositionofListenerPanel         matlab.ui.container.Panel
        cEditFieldLabel                 matlab.ui.control.Label
        cEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        PlaythesoundsPanel              matlab.ui.container.Panel
        PlayimpulseresponsesoundButton  matlab.ui.control.Button
        PlaywavfilewithaddedreverbButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ExplanationLabel                matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: PlaywavfilewithaddedreverbButton
        function PlaywavfilewithaddedreverbButtonPushed(app, event)
            %% Preamble and assigning variables

% Set the sample rate
Fs = 44100;

% Give dimensions of the room
Lx = app.LxEditField.Value;
Ly = app.LyEditField.Value;
Lz = app.LzEditField.Value;

% Put them into vector form
L = [Lx, Ly, Lz];

% Give an error code
if min(L) < 0
    error('Error, L values must be greater than 0.')
end 

% Position of listener
a = app.aEditField.Value;
b = app.bEditField.Value;
c = app.cEditField.Value;

% Vectorised Listener position
P = [a,b,c];

% Position of source
p = app.pEditField.Value;
q = app.qEditField.Value;
r = app.rEditField.Value;

% Vectorised source
S = [p,q,r];

% Error code
if a > Lx
    error('Error, listener must be located inside of the room.')
end 

if b > Ly
    error('Error, listener must be located inside of the room.')
end 

if c > Lz
    error('Error, listener must be located inside of the room.')
end 

% Areas
Axy = Lx*Ly;
Axz = Lx*Lz;
Ayz = Ly*Lz;

% Area vectorised
A = [Axy, Axz, Ayz];

% Volume
V  = Lx*Ly*Lz;

% Dry Coeff: found some coefficients online

 alpha1 = app.alpha1EditField.Value; % Heavy carpet on concrete
 alpha2 = app.alpha2EditField.Value; % Hardwood
 alpha3 = app.alpha3EditField.Value; % Plaster
 alpha4 = app.alpha4EditField.Value; % plaster
 alpha5 = app.alpha5EditField.Value; % Plaster
 alpha6 = app.alpha6EditField.Value; % Wood
 
 % Vectorise alpha coefficients
 Alpha = [alpha1,alpha2,alpha3,alpha4,alpha5,alpha6];
 
 % Error chech alpha coefficients
 if max(Alpha) >= 1
    error('Error, alpha values must be between 0 and 1.')
 end 

 if min(Alpha) <= 0
    error('Error, alpha values must be between 0 and 1.')
 end 
 
% Set the speed of sound
cair = 343;

% Set the value fo T_60
T = (24*log(10)*V)/(cair*(alpha1*Axy + alpha2*Axy + alpha3*Axz + alpha4*Axz + alpha5*Ayz + alpha6*Ayz));

% Set values of N to integers (maybe I don't need to do this in vector
% format??)

Nx = round((cair*T)/Lx);
Ny = round((cair*T)/Ly);
Nz = round((cair*T)/Lz);

% Set vectors running from -N to N
X = -Nx:Nx;
Y = -Ny:Ny;
Z = -Nz:Nz;

%% Distance in x, y, z dimensions

% Calculate parts of the equation that I will use to work out distances in
% x,y,z dimensions. The power of minus one means that the formula given in
% the assignment handout will still hold: for every odd value of X we minus,
% for every even value of X we add.
CX = X + (1 + (-1).^X)/2;
CY = Y + (1 + (-1).^Y)/2;
CZ = Z + (1 + (-1).^Z)/2;

% Work out distances in respective dimensions using formula in assignment
% sheet, but manipulated for vector format.
x = CX*L(1) + ((-1).^X)*S(1) - P(1);
y = CY*L(2) + ((-1).^Y)*S(2) - P(2);
z = CZ*L(3) + ((-1).^Z)*S(3) - P(3);

% Make 3D matrices of all the distances using the hint in the assignment to
% use later.
[x,y,z] = ndgrid(x,y,z);

% work out distance using pythagoras in 3D matrix format

l = sqrt(x.^2 + y.^2 + z.^2);

%% Find magnitudes

% Set 3D matrices for the indices so we can use them later to find
% magnitudes

[X1, Y1, Z1] = ndgrid(X,Y,Z);

% Set reflection coefficients

R1 = sqrt(1-alpha1);
R2 = sqrt(1-alpha2);
R3 = sqrt(1-alpha3);
R4 = sqrt(1-alpha4);
R5 = sqrt(1-alpha5);
R6 = sqrt(1-alpha6);

% Vectorise reflection coefficients
R = [R1,R2,R3,R4,R5,R5];

% Find R^w values. Similar method as in with for loops but easier to
% implement as the indices are simple. All calculations are done
% simultaneously so its more efficient. Also much clearer. 
% Each R^w value is calculated for each dimension separately. As before,
% there are max N/2 collisions with each wall in each direction.

Rx = R(1).^(abs(0.5.*X1 + (-1 + (-1).^X1)*0.25)).*R(2).^(abs(0.5.*X1 + (1 - (-1).^X1)*0.25));
Ry = R(3).^(abs(0.5.*Y1 + (-1 + (-1).^Y1)*0.25)).*R(4).^(abs(0.5.*Y1 + (1 - (-1).^Y1)*0.25));
Rz = R(5).^(abs(0.5.*Z1 + (-1 + (-1).^Z1)*0.25)).*R(6).^(abs(0.5.*Z1 + (1 - (-1).^Z1)*0.25));

% Work out totoal magnitude by pointwise multiplication of the three 3D
% matrices andusing formula in assignment.

g1 = Rx.*Ry.*Rz;
g = g1./l;

%% Create Impulse Response vector

% Work out time
t = l/cair;
% Work out time in samples. Add one to make it max time.
t_samples = round(t*Fs)+1;

% calculate IR vector using sparse/full method. It matches up the matrices
% of magnitude and of time and adds them together very efficiently. 

IR_vectorise = full(sparse(t_samples(:),1,g(:)));

% Take absolute value

IR_vectorise = IR_vectorise/max(abs(IR_vectorise));

% Plot graph. Interestingly, this graph seems a bit more full than my graph
% when I use for loops. Perhaps I had some inaccuracies in the basic
% assignment that vanish when vectorising it all? Right now the plot is
% commented out.

% plot(IR_vectorise)

% Play the sound of the Impulse response. Right now it is commented out,
 % soundsc(IR_vectorise,Fs);
 
%% Match the IR with a wav file using convolution

% Read in wav file
[y, Fs] = audioread('cath_cut.wav');

%Error check to make sure stereo wav files will also work

if size(y,2) == 2
    y = (y(:,1) + y(:,2))/2; 
end 
y;

% use frequency domain convolution as detailed in the basic assignment:
n = length(y); m = length(IR_vectorise);

% length of convolution vector
N = n + m - 1; 

% Determine power of 2 to zero pad the fft for efficiency
N_conv = 2^(nextpow2(N)); 

% Take the fft of the wav file input
Y=fft(y, N_conv); 		

% Take the fft of the Impulse Response 
XIR=fft(IR_vectorise, N_conv); 

% Take the pointwise multiplication of the two ffts 
R=Y.*XIR;       	   
% Transfer signal back into time domain 
reverb_signal = real(ifft(R, N_conv));   
% take only the length required (after N the vector is just zero padded)
reverb_signal = reverb_signal(1:N);     
% Obviously normalise the output
reverb_signal= reverb_signal/max(abs(reverb_signal)); 

% Play the reverb added wav. file:
soundsc(reverb_signal,Fs);
        end

        % Button pushed function: PlayimpulseresponsesoundButton
        function PlayimpulseresponsesoundButtonPushed(app, event)
           % Set the sample rate
Fs = 44100;

% Give dimensions of the room
Lx = app.LxEditField.Value;
Ly = app.LyEditField.Value;
Lz = app.LzEditField.Value;

% Put them into vector form
L = [Lx, Ly, Lz];

% Give an error code
if min(L) < 0
    error('Error, L values must be greater than 0.')
end 

% Position of listener
a = app.aEditField.Value;
b = app.bEditField.Value;
c = app.cEditField.Value;

% Vectorised Listener position
P = [a,b,c];

% Position of source
p = app.pEditField.Value;
q = app.qEditField.Value;
r = app.rEditField.Value;

% Vectorised source
S = [p,q,r];

% Error code
if a > Lx
    error('Error, listener must be located inside of the room.')
end 

if b > Ly
    error('Error, listener must be located inside of the room.')
end 

if c > Lz
    error('Error, listener must be located inside of the room.')
end 

% Areas
Axy = Lx*Ly;
Axz = Lx*Lz;
Ayz = Ly*Lz;

% Area vectorised
A = [Axy, Axz, Ayz];

% Volume
V  = Lx*Ly*Lz;

% Dry Coeff: found some coefficients online

 alpha1 = app.alpha1EditField.Value; % Heavy carpet on concrete
 alpha2 = app.alpha2EditField.Value; % Hardwood
 alpha3 = app.alpha3EditField.Value; % Plaster
 alpha4 = app.alpha4EditField.Value; % plaster
 alpha5 = app.alpha5EditField.Value; % Plaster
 alpha6 = app.alpha6EditField.Value; % Wood
 
 % Vectorise alpha coefficients
 Alpha = [alpha1,alpha2,alpha3,alpha4,alpha5,alpha6];
 
 % Error chech alpha coefficients
 if max(Alpha) >= 1
    error('Error, alpha values must be between 0 and 1.')
 end 

 if min(Alpha) <= 0
    error('Error, alpha values must be between 0 and 1.')
 end 
 
% Set the speed of sound
cair = 343;

% Set the value fo T_60
T = (24*log(10)*V)/(cair*(alpha1*Axy + alpha2*Axy + alpha3*Axz + alpha4*Axz + alpha5*Ayz + alpha6*Ayz));

% Set values of N to integers (maybe I don't need to do this in vector
% format??)

Nx = round((cair*T)/Lx);
Ny = round((cair*T)/Ly);
Nz = round((cair*T)/Lz);

% Set vectors running from -N to N
X = -Nx:Nx;
Y = -Ny:Ny;
Z = -Nz:Nz;

%% Distance in x, y, z dimensions

% Calculate parts of the equation that I will use to work out distances in
% x,y,z dimensions. The power of minus one means that the formula given in
% the assignment handout will still hold: for every odd value of X we minus,
% for every even value of X we add.
CX = X + (1 + (-1).^X)/2;
CY = Y + (1 + (-1).^Y)/2;
CZ = Z + (1 + (-1).^Z)/2;

% Work out distances in respective dimensions using formula in assignment
% sheet, but manipulated for vector format.
x = CX*L(1) + ((-1).^X)*S(1) - P(1);
y = CY*L(2) + ((-1).^Y)*S(2) - P(2);
z = CZ*L(3) + ((-1).^Z)*S(3) - P(3);

% Make 3D matrices of all the distances using the hint in the assignment to
% use later.
[x,y,z] = ndgrid(x,y,z);

% work out distance using pythagoras in 3D matrix format

l = sqrt(x.^2 + y.^2 + z.^2);

%% Find magnitudes

% Set 3D matrices for the indices so we can use them later to find
% magnitudes

[X1, Y1, Z1] = ndgrid(X,Y,Z);

% Set reflection coefficients

R1 = sqrt(1-alpha1);
R2 = sqrt(1-alpha2);
R3 = sqrt(1-alpha3);
R4 = sqrt(1-alpha4);
R5 = sqrt(1-alpha5);
R6 = sqrt(1-alpha6);

% Vectorise reflection coefficients
R = [R1,R2,R3,R4,R5,R5];

% Find R^w values. Similar method as in with for loops but easier to
% implement as the indices are simple. All calculations are done
% simultaneously so its more efficient. Also much clearer. 
% Each R^w value is calculated for each dimension separately. As before,
% there are max N/2 collisions with each wall in each direction.

Rx = R(1).^(abs(0.5.*X1 + (-1 + (-1).^X1)*0.25)).*R(2).^(abs(0.5.*X1 + (1 - (-1).^X1)*0.25));
Ry = R(3).^(abs(0.5.*Y1 + (-1 + (-1).^Y1)*0.25)).*R(4).^(abs(0.5.*Y1 + (1 - (-1).^Y1)*0.25));
Rz = R(5).^(abs(0.5.*Z1 + (-1 + (-1).^Z1)*0.25)).*R(6).^(abs(0.5.*Z1 + (1 - (-1).^Z1)*0.25));

% Work out totoal magnitude by pointwise multiplication of the three 3D
% matrices andusing formula in assignment.

g1 = Rx.*Ry.*Rz;
g = g1./l;

%% Create Impulse Response vector

% Work out time
t = l/cair;
% Work out time in samples. Add one to make it max time.
t_samples = round(t*Fs)+1;

% calculate IR vector using sparse/full method. It matches up the matrices
% of magnitude and of time and adds them together very efficiently. 

IR_vectorise = full(sparse(t_samples(:),1,g(:)));

% Take absolute value

IR_vectorise = IR_vectorise/max(abs(IR_vectorise));

% Plot graph. Interestingly, this graph seems a bit more full than my graph
% when I use for loops. Perhaps I had some inaccuracies in the basic
% assignment that vanish when vectorising it all? Right now the plot is
% commented out.

% plot(IR_vectorise)

% Play the sound of the Impulse response. Right now it is commented out,
soundsc(IR_vectorise,Fs);
 

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.RoomDimensionsPanel = uipanel(app.UIFigure);
            app.RoomDimensionsPanel.Title = 'Room Dimensions';
            app.LxEditFieldLabel = uilabel(app.RoomDimensionsPanel);
            app.LxEditFieldLabel.Text = 'Lx';
            app.LxEditField = uieditfield(app.RoomDimensionsPanel, 'numeric');
            app.LxEditField.Value = 12;
            app.LyEditFieldLabel = uilabel(app.RoomDimensionsPanel);
            app.LyEditFieldLabel.Text = 'Ly';
            app.LyEditField = uieditfield(app.RoomDimensionsPanel, 'numeric');
            app.LyEditField.Value = 11;
            app.LzEditFieldLabel = uilabel(app.RoomDimensionsPanel);
            app.LzEditFieldLabel.Text = 'Lz';
            app.LzEditField = uieditfield(app.RoomDimensionsPanel, 'numeric');
            app.LzEditField.Value = 15;
            app.AbsorptioncoefficientsPanel = uipanel(app.UIFigure);
            app.AbsorptioncoefficientsPanel.Title = 'Absorption coefficients';
            app.alpha2EditFieldLabel = uilabel(app.AbsorptioncoefficientsPanel);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.AbsorptioncoefficientsPanel, 'numeric');
            app.alpha2EditField.Value = 0.3;
            app.alpha4EditFieldLabel = uilabel(app.AbsorptioncoefficientsPanel);
            app.alpha4EditFieldLabel.Text = 'alpha4';
            app.alpha4EditField = uieditfield(app.AbsorptioncoefficientsPanel, 'numeric');
            app.alpha4EditField.Value = 0.5;
            app.alpha1EditFieldLabel = uilabel(app.AbsorptioncoefficientsPanel);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.AbsorptioncoefficientsPanel, 'numeric');
            app.alpha1EditField.Value = 0.2;
            app.alpha3EditFieldLabel = uilabel(app.AbsorptioncoefficientsPanel);
            app.alpha3EditFieldLabel.Text = 'alpha3';
            app.alpha3EditField = uieditfield(app.AbsorptioncoefficientsPanel, 'numeric');
            app.alpha3EditField.Value = 0.2;
            app.alpha5EditFieldLabel = uilabel(app.AbsorptioncoefficientsPanel);
            app.alpha5EditFieldLabel.Text = 'alpha5';
            app.alpha5EditField = uieditfield(app.AbsorptioncoefficientsPanel, 'numeric');
            app.alpha5EditField.Value = 0.1;
            app.alpha6EditFieldLabel = uilabel(app.AbsorptioncoefficientsPanel);
            app.alpha6EditFieldLabel.Text = 'alpha6';
            app.alpha6EditField = uieditfield(app.AbsorptioncoefficientsPanel, 'numeric');
            app.alpha6EditField.Value = 0.35;
            app.PositionofSourcePanel = uipanel(app.UIFigure);
            app.PositionofSourcePanel.Title = 'Position of Source';
            app.rEditFieldLabel = uilabel(app.PositionofSourcePanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.PositionofSourcePanel, 'numeric');
            app.rEditField.Value = 2.2;
            app.qEditFieldLabel = uilabel(app.PositionofSourcePanel);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.PositionofSourcePanel, 'numeric');
            app.qEditField.Value = 4.7;
            app.pEditFieldLabel = uilabel(app.PositionofSourcePanel);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.PositionofSourcePanel, 'numeric');
            app.pEditField.Value = 3.5;
            app.PositionofListenerPanel = uipanel(app.UIFigure);
            app.PositionofListenerPanel.Title = 'Position of Listener';
            app.cEditFieldLabel = uilabel(app.PositionofListenerPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.PositionofListenerPanel, 'numeric');
            app.cEditField.Value = 3.1;
            app.bEditFieldLabel = uilabel(app.PositionofListenerPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.PositionofListenerPanel, 'numeric');
            app.bEditField.Value = 5.4;
            app.aEditFieldLabel = uilabel(app.PositionofListenerPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.PositionofListenerPanel, 'numeric');
            app.aEditField.Value = 8.6;
            app.PlaythesoundsPanel = uipanel(app.UIFigure);
            app.PlaythesoundsPanel.Title = 'Play the sounds';
            app.PlayimpulseresponsesoundButton = uibutton(app.PlaythesoundsPanel, 'push');
            app.PlayimpulseresponsesoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayimpulseresponsesoundButtonPushed, true);
            app.PlayimpulseresponsesoundButton.Text = 'Play impulse response sound';
            app.PlaywavfilewithaddedreverbButton = uibutton(app.PlaythesoundsPanel, 'push');
            app.PlaywavfilewithaddedreverbButton.ButtonPushedFcn = createCallbackFcn(app, @PlaywavfilewithaddedreverbButtonPushed, true);
            app.PlaywavfilewithaddedreverbButton.Text = 'Play wav.file with added reverb';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'This GUI lets the user change various variables to see how they affect the sound of the resulting impulse response and '; 'the sound of the reverb-added wav file ''cath_cut.wav''. The user can change the room size [Lx,Ly,Lz] in three '; 'dimensions, the position of the source [p,q,r] and the listener [a,b,c] in the room, as well as the absorption coefficients '; 'of the 6 different surfaces in the room. Other requirements: the position of the source and the listener must be inside '; 'the room, and the alpha coefficients must be between 0 and 1. All values put in must be positive.'};
            app.ExplanationLabel = uilabel(app.UIFigure);
            app.ExplanationLabel.Text = 'Explanation:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ReverbGUI_s1505528_Tingey()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end